classdef Final < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        WindSpeedLabel           matlab.ui.control.Label
        HumidityLabel            matlab.ui.control.Label
        tempLabel                matlab.ui.control.Label
        GetInformationButton     matlab.ui.control.Button
        EditField                matlab.ui.control.EditField
        AddCityButton            matlab.ui.control.Button
        SelectCityDropDown       matlab.ui.control.DropDown
        SelectCityDropDownLabel  matlab.ui.control.Label
        WindSpeedAxes            matlab.ui.control.UIAxes
        HumidityAxes             matlab.ui.control.UIAxes
        TemperatureAxes          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GetInformationButton
        function GetInformationButtonPushed(app, event)
          %Seçilen şehri string olarak alma ve 
          %FetchFiveDayForecast fonksiyonunu çağırma satırları /28 /29
          city = app.SelectCityDropDown.Value;  
          [temp,humidity,windSpeed,time] = fetchFiveDayForecast(city);
          
          %Sıcaklık grafiğinin alınan verilere göre çizildiği kısım.
          plot(app.TemperatureAxes, time, temp, '-o');
          %Seçilen şehri grafik üzerine formatlı şekilde yazdırdığımız kısım.
          title(app.TemperatureAxes, sprintf('Temperature (°C) Forecast for %s',city));  
          xlabel(app.TemperatureAxes, 'Date'); 
          ylabel(app.TemperatureAxes, 'Temperature (°C)'); 

          %Nem grafiğinin alınan verilere göre çizildiği kısım.
          plot(app.HumidityAxes, time, humidity, '-x');
          %Seçilen şehri grafik üzerine formatlı şekilde yazdırdığımız kısım.
          title(app.HumidityAxes, sprintf('Humidity Forecast for %s', city));  
          xlabel(app.HumidityAxes, 'Date'); 
          ylabel(app.HumidityAxes, 'Humidity (%)'); 

          %Rüzgar hızı grafiğinin alınan verilere göre çizildiği kısım.
          plot(app.WindSpeedAxes, time, windSpeed, '-*');
          %Seçilen şehri grafik üzerine formatlı şekilde yazdırdığımız kısım.
          title(app.WindSpeedAxes, sprintf('Wind Speed Forecast for %s ', city));  
          xlabel(app.WindSpeedAxes, 'Date'); 
          ylabel(app.WindSpeedAxes, 'Wind Speed (m/s)'); 
          
          %Anlık sıcaklık değerini yazdırdığımız kısım.Formatlı kullanım.
          app.tempLabel.Text = sprintf('Temperature: %.1f °C', temp(1));   

          %Anlık nem değerini yazdırdığımız kısım.Formatlı kullanım.
          app.HumidityLabel.Text = sprintf('Humidity: %.1f%%', humidity(1));

          %Anlık Rüzgar Hızını yazdırdığımız kısım.Formatlı kullanım.
          app.WindSpeedLabel.Text = sprintf('Wind Speed: %.2f m/s', windSpeed(1));    

        end

        % Button pushed function: AddCityButton
        function AddCityButtonPushed(app, event)

            AddnewCity = app.EditField.Value; %Figür üzerinde bulunan app.EditField kısmında yazdığımız değeri alıp string olarak dönüştürmemize yarar.
    
            if ~isempty(AddnewCity) % Dönüştürdüğümüz string'in boş mu olduğunu kontrol eder.
           
            app.SelectCityDropDown.Items{end+1} = AddnewCity; %Gerekli şartlar sağlanıyorsa app.SelectCityDropDown içerisinde son sıraya ekleme yapar.

            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TemperatureAxes = uiaxes(app.UIFigure);
            title(app.TemperatureAxes, 'Temperature Forecast For')
            xlabel(app.TemperatureAxes, 'Date')
            ylabel(app.TemperatureAxes, 'Temperature')
            zlabel(app.TemperatureAxes, 'Z')
            app.HumidityAxes = uiaxes(app.UIFigure);
            title(app.HumidityAxes, 'Humidity Forecast For')
            xlabel(app.HumidityAxes, 'Date')
            ylabel(app.HumidityAxes, 'Humidity')
            zlabel(app.HumidityAxes, 'Z')
            app.WindSpeedAxes = uiaxes(app.UIFigure);
            title(app.WindSpeedAxes, 'Wind Speed Forecast For')
            xlabel(app.WindSpeedAxes, 'Date')
            ylabel(app.WindSpeedAxes, 'Wind Speed')
            zlabel(app.WindSpeedAxes, 'Z')
            app.SelectCityDropDownLabel = uilabel(app.UIFigure);
            app.SelectCityDropDownLabel.Text = 'Select City';
            app.SelectCityDropDown = uidropdown(app.UIFigure);
            app.SelectCityDropDown.Items = {};
            app.SelectCityDropDown.Value = {};
            app.AddCityButton = uibutton(app.UIFigure, 'push');
            app.AddCityButton.ButtonPushedFcn = createCallbackFcn(app, @AddCityButtonPushed, true);
            app.AddCityButton.Text = 'Add City';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.GetInformationButton = uibutton(app.UIFigure, 'push');
            app.GetInformationButton.ButtonPushedFcn = createCallbackFcn(app, @GetInformationButtonPushed, true);
            app.GetInformationButton.Text = 'Get Information';
            app.tempLabel = uilabel(app.UIFigure);
            app.tempLabel.Text = 'Temperature:';
            app.HumidityLabel = uilabel(app.UIFigure);
            app.HumidityLabel.Text = 'Humidity:';
            app.WindSpeedLabel = uilabel(app.UIFigure);
            app.WindSpeedLabel.Text = 'Wind Speed:';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Final

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end